classdef MainApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        FileMenu                      matlab.ui.container.Menu
        ExitMenu                      matlab.ui.container.Menu
        ViewMenu                      matlab.ui.container.Menu
        PlotOptionsMenu               matlab.ui.container.Menu
        EnablehighqualitymodelsMenu   matlab.ui.container.Menu
        EnablecoordinateframeMenu     matlab.ui.container.Menu
        ToolsMenu                     matlab.ui.container.Menu
        WindowMenu                    matlab.ui.container.Menu
        HelpMenu                      matlab.ui.container.Menu
        AboutMenu                     matlab.ui.container.Menu
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        TabGroup                      matlab.ui.container.TabGroup
        RobotTab                      matlab.ui.container.Tab
        UITable                       matlab.ui.control.Table
        ModelDropDown                 matlab.ui.control.DropDown
        ModelDropDownLabel            matlab.ui.control.Label
        CenterPanel                   matlab.ui.container.Panel
        Panel                         matlab.ui.container.Panel
        HighqualitymodelsSwitch       matlab.ui.control.Switch
        HighqualitymodelsSwitchLabel  matlab.ui.control.Label
        SlowerLabel                   matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
        RightPanel                    matlab.ui.container.Panel
        TabGroup_2                    matlab.ui.container.TabGroup
        ManualTab                     matlab.ui.container.Tab
        Joint6Slider                  matlab.ui.control.Slider
        Joint6SliderLabel             matlab.ui.control.Label
        Joint5Slider                  matlab.ui.control.Slider
        Joint5SliderLabel             matlab.ui.control.Label
        Joint4Slider                  matlab.ui.control.Slider
        Joint4SliderLabel             matlab.ui.control.Label
        Joint3Slider                  matlab.ui.control.Slider
        Joint3SliderLabel             matlab.ui.control.Label
        Joint2Slider                  matlab.ui.control.Slider
        Joint2SliderLabel             matlab.ui.control.Label
        Joint1Slider                  matlab.ui.control.Slider
        Joint1SliderLabel             matlab.ui.control.Label
        Joint7Slider                  matlab.ui.control.Slider
        Joint7SliderLabel             matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end


    properties (Access = public)
        
        robot_name = [
        "Franka Emika Robot"
        "Universal Robots UR3"
        "Unitree Z1"
        "Stäubli RX160"
        "Stäubli RX160L"];

        ms;
        Pobj;

        high_quality;
        coord_frame_on;

        robot_model = 1;

        kin;
        
        TI_0 = eye(4);

        q_pos;
        
    end
    
    methods (Access = public)
        
        function updateRobotModel(app)
            
            app.ModelDropDown.Value = char(app.robot_name(app.robot_model));

            app.kin = kinematicParameters(app.robot_model);
            
            app.q_pos = [];
            for j = 1:app.kin.n
                app.q_pos(j) = (app.kin.q_posLim(j,1)+app.kin.q_posLim(j,2))/2;
            end

            app.Joint1Slider.Limits = rad2deg(app.kin.q_posLim(1,:));
            app.Joint2Slider.Limits = rad2deg(app.kin.q_posLim(2,:));
            app.Joint3Slider.Limits = rad2deg(app.kin.q_posLim(3,:));
            app.Joint4Slider.Limits = rad2deg(app.kin.q_posLim(4,:));
            app.Joint5Slider.Limits = rad2deg(app.kin.q_posLim(5,:));
            app.Joint6Slider.Limits = rad2deg(app.kin.q_posLim(6,:));
            app.Joint1Slider.Value = rad2deg(app.q_pos(1));
            app.Joint2Slider.Value = rad2deg(app.q_pos(2));
            app.Joint3Slider.Value = rad2deg(app.q_pos(3));
            app.Joint4Slider.Value = rad2deg(app.q_pos(4));
            app.Joint5Slider.Value = rad2deg(app.q_pos(5));
            app.Joint6Slider.Value = rad2deg(app.q_pos(6));
            if app.kin.n == 6
                app.UITable.Data = {app.kin.a_j(1) app.kin.alpha_j(1) app.kin.d_j(1) append(num2str(app.kin.theta_O_j(1)), ' + q1'); ...
                                    app.kin.a_j(2) app.kin.alpha_j(2) app.kin.d_j(2) append(num2str(app.kin.theta_O_j(2)), ' + q2'); ...
                                    app.kin.a_j(3) app.kin.alpha_j(3) app.kin.d_j(3) append(num2str(app.kin.theta_O_j(3)), ' + q3'); ...
                                    app.kin.a_j(4) app.kin.alpha_j(4) app.kin.d_j(4) append(num2str(app.kin.theta_O_j(4)), ' + q4'); ...
                                    app.kin.a_j(5) app.kin.alpha_j(5) app.kin.d_j(5) append(num2str(app.kin.theta_O_j(5)), ' + q5'); ...
                                    app.kin.a_j(6) app.kin.alpha_j(6) app.kin.d_j(6) append(num2str(app.kin.theta_O_j(6)), ' + q6'); ...
                                    app.kin.a_j(7) app.kin.alpha_j(7) app.kin.d_j(7) app.kin.theta_O_j(7)};

                app.Joint7Slider.Visible = 'off';
                app.Joint7SliderLabel.Visible = 'off';
            else
                app.UITable.Data = {app.kin.a_j(1) app.kin.alpha_j(1) app.kin.d_j(1) append(num2str(app.kin.theta_O_j(1)), ' + q1'); ...
                                    app.kin.a_j(2) app.kin.alpha_j(2) app.kin.d_j(2) append(num2str(app.kin.theta_O_j(2)), ' + q2'); ...
                                    app.kin.a_j(3) app.kin.alpha_j(3) app.kin.d_j(3) append(num2str(app.kin.theta_O_j(3)), ' + q3'); ...
                                    app.kin.a_j(4) app.kin.alpha_j(4) app.kin.d_j(4) append(num2str(app.kin.theta_O_j(4)), ' + q4'); ...
                                    app.kin.a_j(5) app.kin.alpha_j(5) app.kin.d_j(5) append(num2str(app.kin.theta_O_j(5)), ' + q5'); ...
                                    app.kin.a_j(6) app.kin.alpha_j(6) app.kin.d_j(6) append(num2str(app.kin.theta_O_j(6)), ' + q6'); ...
                                    app.kin.a_j(7) app.kin.alpha_j(7) app.kin.d_j(7) append(num2str(app.kin.theta_O_j(7)), ' + q7'); ...
                                    app.kin.a_j(8) app.kin.alpha_j(8) app.kin.d_j(8) app.kin.theta_O_j(8)};

                app.Joint7Slider.Limits = rad2deg(app.kin.q_posLim(7,:));
                app.Joint7Slider.Value = rad2deg(app.q_pos(7));
                app.Joint7Slider.Visible = 'on';
                app.Joint7SliderLabel.Visible = 'on';
            end

            addStyle(app.UITable,uistyle('HorizontalAlignment','right'),'table','');
            
        end
        
        function loadModelStruct(app)
        
            cla(app.UIAxes)
            light(app.UIAxes,'Position',[-1 0 1])
            
            % Load CAD files
            if app.robot_model == 5 || app.robot_model == 4
                if app.high_quality == 1
                    temp = load('meshes\rx160\collision\base_link.mat'); app.ms.s0 = temp.modelstruct;
                    temp = load('meshes\rx160\collision\link_1.mat'); app.ms.s1 = temp.modelstruct;
                    temp = load('meshes\rx160\collision\link_2.mat'); app.ms.s2 = temp.modelstruct;
                    temp = load('meshes\rx160\collision\link_3.mat'); app.ms.s3 = temp.modelstruct;
                    if app.robot_model == 5
                        temp = load('meshes\rx160\collision\link_4l.mat'); app.ms.s4 = temp.modelstruct;
                    else
                        temp = load('meshes\rx160\collision\link_4.mat'); app.ms.s4 = temp.modelstruct;
                    end
                    temp = load('meshes\rx160\collision\link_5.mat'); app.ms.s5 = temp.modelstruct;
                    temp = load('meshes\rx160\collision\link_6.mat'); app.ms.s6 = temp.modelstruct;
                else
                    temp = load('meshes\rx160\visual\base_link.mat'); app.ms.s0 = temp.modelstruct;
                    temp = load('meshes\rx160\visual\link_1.mat'); app.ms.s1 = temp.modelstruct;
                    temp = load('meshes\rx160\visual\link_2.mat'); app.ms.s2 = temp.modelstruct;
                    temp = load('meshes\rx160\visual\link_3.mat'); app.ms.s3 = temp.modelstruct;
                    if app.robot_model == 5
                        temp = load('meshes\rx160\visual\link_4l.mat'); app.ms.s4 = temp.modelstruct;
                    else
                        temp = load('meshes\rx160\visual\link_4.mat'); app.ms.s4 = temp.modelstruct;
                    end
                    temp = load('meshes\rx160\visual\link_5.mat'); app.ms.s5 = temp.modelstruct;
                    temp = load('meshes\rx160\visual\link_6.mat'); app.ms.s6 = temp.modelstruct;
                end
            elseif app.robot_model == 3
                temp = load('meshes\z1\z1_Link00.mat'); app.ms.s0 = temp.modelstruct;
                temp = load('meshes\z1\z1_Link01.mat'); app.ms.s1 = temp.modelstruct;
                temp = load('meshes\z1\z1_Link02.mat'); app.ms.s2 = temp.modelstruct;
                temp = load('meshes\z1\z1_Link03.mat'); app.ms.s3 = temp.modelstruct;
                temp = load('meshes\z1\z1_Link04.mat'); app.ms.s4 = temp.modelstruct;
                temp = load('meshes\z1\z1_Link05.mat'); app.ms.s5 = temp.modelstruct;
                temp = load('meshes\z1\z1_Link06.mat'); app.ms.s6 = temp.modelstruct;
            elseif app.robot_model == 2
                temp = load('meshes\ur3\base.mat');     app.ms.s0 = temp.modelstruct;
                temp = load('meshes\ur3\shoulder.mat'); app.ms.s1 = temp.modelstruct;
                temp = load('meshes\ur3\upperarm.mat'); app.ms.s2 = temp.modelstruct;
                temp = load('meshes\ur3\forearm.mat');  app.ms.s3 = temp.modelstruct;
                temp = load('meshes\ur3\wrist1.mat');   app.ms.s4 = temp.modelstruct;
                temp = load('meshes\ur3\wrist2.mat');   app.ms.s5 = temp.modelstruct;
                temp = load('meshes\ur3\wrist3.mat');   app.ms.s6 = temp.modelstruct;
            else
                if app.high_quality == 1
                    temp = load('meshes\fer\visual\link0.mat');   app.ms.s0 = temp.modelstruct;
                    temp = load('meshes\fer\visual\link1.mat');   app.ms.s1 = temp.modelstruct;
                    temp = load('meshes\fer\visual\link2.mat');   app.ms.s2 = temp.modelstruct;
                    temp = load('meshes\fer\visual\link3.mat');   app.ms.s3 = temp.modelstruct;
                    temp = load('meshes\fer\visual\link4.mat');   app.ms.s4 = temp.modelstruct;
                    temp = load('meshes\fer\visual\link5.mat');   app.ms.s5 = temp.modelstruct;
                    temp = load('meshes\fer\visual\link6.mat');   app.ms.s6 = temp.modelstruct;
                    temp = load('meshes\fer\visual\link7.mat');   app.ms.s7 = temp.modelstruct;
                else
                    temp = load('meshes\fer\collision\link0.mat');    app.ms.s0 = temp.modelstruct;
                    temp = load('meshes\fer\collision\link1.mat');    app.ms.s1 = temp.modelstruct;
                    temp = load('meshes\fer\collision\link2.mat');    app.ms.s2 = temp.modelstruct;
                    temp = load('meshes\fer\collision\link3.mat');    app.ms.s3 = temp.modelstruct;
                    temp = load('meshes\fer\collision\link4.mat');    app.ms.s4 = temp.modelstruct;
                    temp = load('meshes\fer\collision\link5.mat');    app.ms.s5 = temp.modelstruct;
                    temp = load('meshes\fer\collision\link6.mat');    app.ms.s6 = temp.modelstruct;
                    temp = load('meshes\fer\collision\link7.mat');    app.ms.s7 = temp.modelstruct;
                end
            end
            
            if app.coord_frame_on == 1
                temp = load('meshes\coord_frame.mat');  app.ms.sF = temp.modelstruct;
            end

            clear temp
            
            % CAD properties
            app.Pobj.p0 = patch(app.UIAxes,'Faces', app.ms.s0.F, 'Vertices', app.ms.s0.V);
            set(app.Pobj.p0, 'facec', 'flat');                    % Set the face color flat
            set(app.Pobj.p0, 'FaceColor', app.ms.s0.C);           % Set the color (from file)
            set(app.Pobj.p0, 'EdgeColor', 'none');                % Set the edge color
            app.Pobj.p1 = patch(app.UIAxes,'Faces', app.ms.s1.F, 'Vertices', app.ms.s1.V);
            set(app.Pobj.p1, 'facec', 'flat');                    % Set the face color flat
            if app.robot_model == 2
                set(app.Pobj.p1, 'FaceVertexCData', app.ms.s1.C); % Set the color (from file)
            else
                set(app.Pobj.p1, 'FaceColor', app.ms.s1.C);       % Set the color (from file)
            end
            set(app.Pobj.p1, 'EdgeColor', 'none');                % Set the edge color
            app.Pobj.p2 = patch(app.UIAxes,'Faces', app.ms.s2.F, 'Vertices', app.ms.s2.V);
            set(app.Pobj.p2, 'facec', 'flat');                    % Set the face color flat
            if app.robot_model == 2
                set(app.Pobj.p2, 'FaceVertexCData', app.ms.s2.C); % Set the color (from file)
            else
                set(app.Pobj.p2, 'FaceColor', app.ms.s2.C);       % Set the color (from file)
            end
            set(app.Pobj.p2, 'EdgeColor', 'none');                % Set the edge color
            app.Pobj.p3 = patch(app.UIAxes,'Faces', app.ms.s3.F, 'Vertices', app.ms.s3.V);
            set(app.Pobj.p3, 'facec', 'flat');                    % Set the face color flat
            if app.robot_model == 2
                set(app.Pobj.p3, 'FaceVertexCData', app.ms.s3.C); % Set the color (from file)
            else
                set(app.Pobj.p3, 'FaceColor', app.ms.s3.C);       % Set the color (from file)
            end
            set(app.Pobj.p3, 'EdgeColor', 'none');                % Set the edge color
            app.Pobj.p4 = patch(app.UIAxes,'Faces', app.ms.s4.F, 'Vertices', app.ms.s4.V);
            set(app.Pobj.p4, 'facec', 'flat');                    % Set the face color flat
            if app.robot_model == 2
                set(app.Pobj.p4, 'FaceVertexCData', app.ms.s4.C); % Set the color (from file)
            else
                set(app.Pobj.p4, 'FaceColor', app.ms.s4.C);       % Set the color (from file)
            end
            set(app.Pobj.p4, 'EdgeColor', 'none');                % Set the edge color
            app.Pobj.p5 = patch(app.UIAxes,'Faces', app.ms.s5.F, 'Vertices', app.ms.s5.V);
            set(app.Pobj.p5, 'facec', 'flat');                    % Set the face color flat
            if app.robot_model == 2
                set(app.Pobj.p5, 'FaceVertexCData', app.ms.s5.C); % Set the color (from file)
            else
                set(app.Pobj.p5, 'FaceColor', app.ms.s5.C);       % Set the color (from file)
            end
            set(app.Pobj.p5, 'EdgeColor', 'none');                % Set the edge color
            app.Pobj.p6 = patch(app.UIAxes,'Faces', app.ms.s6.F, 'Vertices', app.ms.s6.V);
            set(app.Pobj.p6, 'facec', 'flat');                    % Set the face color flat
            if app.robot_model == 2
                set(app.Pobj.p6, 'FaceVertexCData', app.ms.s6.C); % Set the color (from file)
            else
                set(app.Pobj.p6, 'FaceColor', app.ms.s6.C);       % Set the color (from file)
            end
            set(app.Pobj.p6, 'EdgeColor', 'none');                % Set the edge color
            if app.robot_model == 1
                app.Pobj.p7 = patch(app.UIAxes,'Faces', app.ms.s7.F, 'Vertices', app.ms.s7.V);
                set(app.Pobj.p7, 'facec', 'flat');                % Set the face color flat
                set(app.Pobj.p7, 'FaceColor', app.ms.s7.C);       % Set the color (from file)
                set(app.Pobj.p7, 'EdgeColor', 'none');            % Set the edge color     
            end
            if app.coord_frame_on == 1
                app.Pobj.pF = patch(app.UIAxes,'Faces', app.ms.sF.F, 'Vertices', app.ms.sF.V);
                set(app.Pobj.pF, 'facec', 'flat');                % Set the face color flat
                set(app.Pobj.pF, 'FaceVertexCData', app.ms.sF.C); % Set the color (from file)
                set(app.Pobj.pF, 'EdgeColor', 'none');            % Set the edge color
            end
            
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.EnablehighqualitymodelsMenu.Checked = 'on';
            app.HighqualitymodelsSwitch.Value = 'On';
            app.high_quality = 1;
            app.EnablecoordinateframeMenu.Checked = 'on';
            app.coord_frame_on = 1;

            updateRobotModel(app)

            view(app.UIAxes,3)  % Isometric view
            
            xlabel(app.UIAxes,'X')
            ylabel(app.UIAxes,'Y')
            zlabel(app.UIAxes,'Z')
            grid(app.UIAxes,'on')

            set(app.UIAxes,	'Projection','perspective', ...
                            'PlotBoxAspectRatio',[1 1 1], ...
                            'DataAspectRatio',[1 1 1]);
            
            app.UIAxes.Interactions = rotateInteraction;
%             app.UIAxes.Interactions = [zoomInteraction regionZoomInteraction rulerPanInteraction];
            app.UIAxes.Toolbar = [];
%             zlim(app.UIAxes,[-0.6 inf])

            loadModelStruct(app)
            plotAxesOutApp(app)

        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            delete(app);
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            message = sprintf('Robot Simulation Program\nVersion 0.000003');
            uialert(app.UIFigure,message,'About','Icon','info');
        end

        % Value changed function: ModelDropDown
        function ModelDropDownValueChanged(app, event)
            value = app.ModelDropDown.Value;
            
            for i = size(app.robot_name,1):-1:1
                if strcmp(value, app.robot_name(i))
                    app.robot_model = i;
                end
            end

            updateRobotModel(app)
            loadModelStruct(app)
            plotAxesOutApp(app)
        end

        % Value changing function: Joint1Slider
        function Joint1SliderValueChanging(app, event)
            app.q_pos(1) = deg2rad(event.Value);
            plotAxesOutApp(app)
        end

        % Value changing function: Joint2Slider
        function Joint2SliderValueChanging(app, event)
            app.q_pos(2) = deg2rad(event.Value);
            plotAxesOutApp(app)
        end

        % Value changing function: Joint3Slider
        function Joint3SliderValueChanging(app, event)
            app.q_pos(3) = deg2rad(event.Value);
            plotAxesOutApp(app)
        end

        % Value changing function: Joint4Slider
        function Joint4SliderValueChanging(app, event)
            app.q_pos(4) = deg2rad(event.Value);
            plotAxesOutApp(app)
        end

        % Value changing function: Joint5Slider
        function Joint5SliderValueChanging(app, event)
            app.q_pos(5) = deg2rad(event.Value);
            plotAxesOutApp(app)
        end

        % Value changing function: Joint6Slider
        function Joint6SliderValueChanging(app, event)
            app.q_pos(6) = deg2rad(event.Value);
            plotAxesOutApp(app)
        end

        % Value changing function: Joint7Slider
        function Joint7SliderValueChanging(app, event)
            app.q_pos(7) = deg2rad(event.Value);
            plotAxesOutApp(app)
        end

        % Value changed function: HighqualitymodelsSwitch
        function HighqualitymodelsSwitchValueChanged(app, event)
            value = app.HighqualitymodelsSwitch.Value;

            if strcmpi(value,'On') == 1
                app.high_quality = 1;
                app.EnablehighqualitymodelsMenu.Checked = 'on';
            else
                app.high_quality = 0;
                app.EnablehighqualitymodelsMenu.Checked = 'off';
            end
            
            loadModelStruct(app)
            plotAxesOutApp(app)
        end

        % Menu selected function: EnablehighqualitymodelsMenu
        function EnablehighqualitymodelsMenuSelected(app, event)
            if app.high_quality == 1
                app.EnablehighqualitymodelsMenu.Checked = 'off';
                app.HighqualitymodelsSwitch.Value = 'Off';
                app.high_quality = 0;
            else
                app.EnablehighqualitymodelsMenu.Checked = 'on';
                app.HighqualitymodelsSwitch.Value = 'On';
                app.high_quality = 1;
            end

            loadModelStruct(app)
            plotAxesOutApp(app)
        end

        % Menu selected function: EnablecoordinateframeMenu
        function EnablecoordinateframeMenuSelected(app, event)
            if app.coord_frame_on == 1
                app.EnablecoordinateframeMenu.Checked = 'off';
                app.coord_frame_on = 0;
            else
                app.EnablecoordinateframeMenu.Checked = 'on';
                app.coord_frame_on = 1;
            end

            loadModelStruct(app)
            plotAxesOutApp(app)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {266, '1x', 267};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Text = 'Exit';
            app.ViewMenu = uimenu(app.UIFigure);
            app.ViewMenu.Text = 'View';
            app.PlotOptionsMenu = uimenu(app.ViewMenu);
            app.PlotOptionsMenu.Text = 'Plot Options';
            app.EnablehighqualitymodelsMenu = uimenu(app.PlotOptionsMenu);
            app.EnablehighqualitymodelsMenu.MenuSelectedFcn = createCallbackFcn(app, @EnablehighqualitymodelsMenuSelected, true);
            app.EnablehighqualitymodelsMenu.Text = 'Enable high quality models';
            app.EnablecoordinateframeMenu = uimenu(app.PlotOptionsMenu);
            app.EnablecoordinateframeMenu.MenuSelectedFcn = createCallbackFcn(app, @EnablecoordinateframeMenuSelected, true);
            app.EnablecoordinateframeMenu.Text = 'Enable coordinate frame';
            app.ToolsMenu = uimenu(app.UIFigure);
            app.ToolsMenu.Text = 'Tools';
            app.WindowMenu = uimenu(app.UIFigure);
            app.WindowMenu.Text = 'Window';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.RobotTab = uitab(app.TabGroup);
            app.RobotTab.Title = 'Robot';
            app.ModelDropDownLabel = uilabel(app.RobotTab);
            app.ModelDropDownLabel.Text = 'Model';
            app.ModelDropDown = uidropdown(app.RobotTab);
            app.ModelDropDown.Items = {'Franka Emika Robot', 'Universal Robots UR3', 'Unitree Z1', 'Stäubli RX160', 'Stäubli RX160L'};
            app.ModelDropDown.ValueChangedFcn = createCallbackFcn(app, @ModelDropDownValueChanged, true);
            app.ModelDropDown.Value = 'Franka Emika Robot';
            app.UITable = uitable(app.RobotTab);
            app.UITable.ColumnName = {'a_j'; 'alpha_j'; 'd_j'; 'theta_j'};
            app.UITable.RowName = {};
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.CenterPanel);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Panel = uipanel(app.CenterPanel);
            app.SlowerLabel = uilabel(app.Panel);
            app.SlowerLabel.Text = '(Slower!)';
            app.HighqualitymodelsSwitchLabel = uilabel(app.Panel);
            app.HighqualitymodelsSwitchLabel.Text = 'High quality models:';
            app.HighqualitymodelsSwitch = uiswitch(app.Panel, 'slider');
            app.HighqualitymodelsSwitch.ValueChangedFcn = createCallbackFcn(app, @HighqualitymodelsSwitchValueChanged, true);
            app.RightPanel = uipanel(app.GridLayout);
            app.Joint7SliderLabel = uilabel(app.RightPanel);
            app.Joint7SliderLabel.Text = 'Joint 7';
            app.Joint7Slider = uislider(app.RightPanel);
            app.Joint7Slider.ValueChangingFcn = createCallbackFcn(app, @Joint7SliderValueChanging, true);
            app.TabGroup_2 = uitabgroup(app.RightPanel);
            app.ManualTab = uitab(app.TabGroup_2);
            app.ManualTab.Title = 'Manual';
            app.Joint1SliderLabel = uilabel(app.ManualTab);
            app.Joint1SliderLabel.Text = 'Joint 1';
            app.Joint1Slider = uislider(app.ManualTab);
            app.Joint1Slider.ValueChangingFcn = createCallbackFcn(app, @Joint1SliderValueChanging, true);
            app.Joint2SliderLabel = uilabel(app.ManualTab);
            app.Joint2SliderLabel.Text = 'Joint 2';
            app.Joint2Slider = uislider(app.ManualTab);
            app.Joint2Slider.ValueChangingFcn = createCallbackFcn(app, @Joint2SliderValueChanging, true);
            app.Joint3SliderLabel = uilabel(app.ManualTab);
            app.Joint3SliderLabel.Text = 'Joint 3';
            app.Joint3Slider = uislider(app.ManualTab);
            app.Joint3Slider.ValueChangingFcn = createCallbackFcn(app, @Joint3SliderValueChanging, true);
            app.Joint4SliderLabel = uilabel(app.ManualTab);
            app.Joint4SliderLabel.Text = 'Joint 4';
            app.Joint4Slider = uislider(app.ManualTab);
            app.Joint4Slider.ValueChangingFcn = createCallbackFcn(app, @Joint4SliderValueChanging, true);
            app.Joint5SliderLabel = uilabel(app.ManualTab);
            app.Joint5SliderLabel.Text = 'Joint 5';
            app.Joint5Slider = uislider(app.ManualTab);
            app.Joint5Slider.ValueChangingFcn = createCallbackFcn(app, @Joint5SliderValueChanging, true);
            app.Joint6SliderLabel = uilabel(app.ManualTab);
            app.Joint6SliderLabel.Text = 'Joint 6';
            app.Joint6Slider = uislider(app.ManualTab);
            app.Joint6Slider.ValueChangingFcn = createCallbackFcn(app, @Joint6SliderValueChanging, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MainApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end